classdef FilePath < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PreSelectedFileoptionsDropDown  matlab.ui.control.DropDown
        PreSelectedFileoptionsDropDownLabel  matlab.ui.control.Label
        startButton                     matlab.ui.control.Button
        UploadfileButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
        fileName % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainFilterApp)
            app.MainApp = mainFilterApp;
        end

        % Button pushed function: UploadfileButton
        function UploadfileButtonPushed(app, event)
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            if (ischar(p))
               app.fileName= [p f];
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: PreSelectedFileoptionsDropDown
        function PreSelectedFileoptionsDropDownValueChanged(app, event)
            value = app.PreSelectedFileoptionsDropDown.Value;
            if value == "pears"
                app.fileName = strcat(fileparts(which('pears.png')), '\pears.png'); 
            elseif value == "dog"
                app.fileName = strcat(fileparts(which('kobi.png')), '\kobi.png');
            elseif value == "flower"
                app.fileName = strcat(fileparts(which('yellowlily.jpg')), '\yellowlily.jpg');
            elseif value == "cathedral"
                app.fileName = strcat(fileparts(which('lowlight_1.jpg')), '\lowlight_1.jpg');
            end
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            uploadImage(app.MainApp, app.fileName); 
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UploadfileButton = uibutton(app.UIFigure, 'push');
            app.UploadfileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadfileButtonPushed, true);
            app.UploadfileButton.Text = 'Upload file';
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.PreSelectedFileoptionsDropDownLabel = uilabel(app.UIFigure);
            app.PreSelectedFileoptionsDropDownLabel.Text = 'Pre-Selected File options';
            app.PreSelectedFileoptionsDropDown = uidropdown(app.UIFigure);
            app.PreSelectedFileoptionsDropDown.Items = {'Pick One', 'pears', 'dog', 'flower', 'cathedral'};
            app.PreSelectedFileoptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @PreSelectedFileoptionsDropDownValueChanged, true);
            app.PreSelectedFileoptionsDropDown.Value = 'Pick One';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilePath(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end