classdef MainFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        AdjustPanel           matlab.ui.container.Panel
        BlueSlider            matlab.ui.control.Slider
        BlueSliderLabel       matlab.ui.control.Label
        GreenSlider           matlab.ui.control.Slider
        GreenSliderLabel      matlab.ui.control.Label
        EdgesSlider           matlab.ui.control.Slider
        EdgesSliderLabel      matlab.ui.control.Label
        BlurSlider            matlab.ui.control.Slider
        BlurSliderLabel       matlab.ui.control.Label
        NoiseSlider           matlab.ui.control.Slider
        NoiseSliderLabel      matlab.ui.control.Label
        RedSlider             matlab.ui.control.Slider
        RedSliderLabel        matlab.ui.control.Label
        FiltersDropDown       matlab.ui.control.DropDown
        FiltersDropDownLabel  matlab.ui.control.Label
        NewImageButton        matlab.ui.control.Button
        StartoverButton       matlab.ui.control.Button
        SaveImageButton       matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imagePath  % The path to the file
        imagOriginal    %original matrix of the image
        currentImage    %current matrix of the image
        RValue          %current red value of image
        BValue          %current blue value of image
        GValue          %current green value of image
    end
    
    methods (Access = public)
        %this function uploads the image onto this figure with
        %the path name given from the other class
        function uploadImage(app, name)
            app.imagePath = name;
            imag = imread(name);
            app.imagOriginal = imag;
            app.currentImage = imag;
            imagesc(app.UIAxes,imag);
            app.UIAxes.Colormap = gray(256);
            app.RValue = app.currentImage(:,:,1);
            app.GValue = app.currentImage(:,:,2);
            app.BValue = app.currentImage(:,:,3);
            clc;
        end
        function updateColor(app)
            %this calls all of the color functions to update the pics
            %colors
            app.RedSliderValueChanged(app);
            app.GreenSliderValueChanged(app);
            app.BlueSliderValueChanged(app);
        end
        function updateFilters(app, filterCalled)
            %this calls all of the filters functions minus the one that
            %called this function
            if(filterCalled ~= "Blur")
                app.BlurSliderValueChanged(app);
            elseif(filterCalled ~= "Edges")
                app.EdgesSliderValueChanged(app);
            elseif(filterCalled ~= "Noise")
                app.NoiseSliderValueChanged(app);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %opening the filepath window to get the image to use
            app.imagePath = FilePath(app);
            axis(app.UIAxes, 'image');
        end

        % Button pushed function: NewImageButton
        function NewImageButtonPushed(app, event)
            %re-opens the window to upload new image
            app.imagePath = FilePath(app);
        end

        % Button pushed function: StartoverButton
        function StartoverButtonPushed(app, event)
            %this button will reset everything to start over just incase
            %the currentimage cant be reversed on its own
            app.FiltersDropDown.Value="None";
            app.FiltersDropDownValueChanged(app);
            image(app.UIAxes, app.imagOriginal);
            app.UIAxes.Colormap = gray(256);
            app.RValue = app.imagOriginal(:,:,1);
            app.GValue = app.imagOriginal(:,:,2);
            app.BValue = app.imagOriginal(:,:,3);
            app.currentImage = app.imagOriginal;
            app.RedSlider.Value=1;
            app.GreenSlider.Value=2;
            app.BlueSlider.Value=3;
            app.NoiseSlider.Value=0;
            app.BlurSlider.Value=0;
            app.EdgesSlider.Value=0;
        end

        % Value changed function: FiltersDropDown
        function FiltersDropDownValueChanged(app, event)
            %These are a list of premade filters to choose from
            value = app.FiltersDropDown.Value;
            %this filter brigthens a low light image
            if strcmp(value, "Brighten")
                %taking the complement of an image will result in the
                %inverted colors
                %using the reduse haze function then re-inverting the image
                %results in a brigther image.
                AInv = imcomplement(app.currentImage);
                BInv = imreducehaze(AInv);
                newImage = imcomplement(BInv);
                app.currentImage = newImage;
                imagesc(app.UIAxes, app.currentImage);
            end
            %this filter is graying out the image
            if strcmp(value, "Gray")
                %since colormap does not work on truecolor images getting
                %the linear combination between the two gives a grayscale image
                grayMatrix = [0.30, 0.59, 0.11];
                diff = imapplymatrix(grayMatrix, app.currentImage);
                imagesc(app.UIAxes, diff);
            end
            %showing the details of the image
            if strcmp(value, "Details")
                %Converting the image to the appropriate size array but it
                %loses some details due to being a truecolor image 
                %Filtering the image shows the lines of the image
                Imag2D = rgb2gray(app.currentImage);
                filteredImage = uint8(filter2(fspecial('gaussian'), Imag2D));
                newImage = imabsdiff(Imag2D, filteredImage);
                %brigthening up the reults so it can show better
                AInv = imcomplement(newImage);
                BInv = imreducehaze(AInv);
                newImage = imcomplement(BInv);
                app.currentImage = newImage;
                imagesc(app.UIAxes, newImage);
            end
        end

        % Value changed function: BlurSlider
        function BlurSliderValueChanged(app, event)
            value = app.BlurSlider.Value;
            %creating a blurring kernel to blur the image
            blurringKernel = ones(int8(value))/double(value^2);
            %if this function was called by an event then it will update
            %the image by calling the other functions then show the current
            %image. Else it just updates the current image with this filter.
            if isa(event, 'matlab.ui.eventdata.ValueChangedData')
                %calling the colors to be reapplied. Doing this ever so
                %often throught the code makes it so the image looks better
                app.currentImage = app.imagOriginal;
                
                if value ~= 0
                    newImage = imfilter(app.currentImage, blurringKernel);
                    app.currentImage = newImage;
                else
                    app.currentImage = app.imagOriginal;
                    updateColor(app);
                end
                %calling the other functions to get thit reapplied to the
                %new image
                updateFilters(app, "Blur");
                imagesc(app.UIAxes, app.currentImage);
            else
                %if the value was set to zero then nothing happens
                if value ~= 0
                    newImage = imfilter(app.currentImage, blurringKernel);
                    app.currentImage = newImage;
                end
            end
        end

        % Value changed function: EdgesSlider
        function EdgesSliderValueChanged(app, event)
            value = app.EdgesSlider.Value;
            %making it so the user will only use even numbers
            value = round(value)+mod(round(value), 2);
            app.EdgesSlider.Value = value;
            %creating the egde kernal to filter ontop of the image
            kernel = -ones(value);
            center = floor(value/2)+1;
            kernel(center, center)= value^2;
            %if this function was called by an event then it will update
            %the image by calling the other functions then show the current
            %image. Else it just updates the current image with this filter.
            if isa(event, 'matlab.ui.eventdata.ValueChangedData')
                app.currentImage = app.imagOriginal;
                %calling the colors to be reapplied. Doing this ever so
                %often throught the code makes it so the image looks better
                updateColor(app);
                if value ~=0
                    newImage = imfilter(app.currentImage, kernel);
                    app.currentImage = newImage;
                else
                    app.currentImage = app.imagOriginal;
                    updateColor(app);
                end
                %calling the other functions to get thit reapplied to the
                %new image
                updateFilters(app, "Edges");
                imagesc(app.UIAxes, app.currentImage);
            else
                if value~=0
                newImage = imfilter(app.currentImage, kernel);
                app.currentImage = newImage;
                end
            end
        end

        % Value changed function: RedSlider
        function RedSliderValueChanged(app, event)
            value = app.RedSlider.Value;
            %rounding the value since the function doesn't work on floating
            %numbers
            value = round(value);
            app.RedSlider.Value=value;
            %if this function was called by an event then show the current
            %image. Else it just updates the current image with this filter.
            if isa(event, 'matlab.ui.eventdata.ValueChangedData')
                if value~=0
                    app.RValue = app.currentImage(:,:,value);
                    Rimg = cat(3, app.RValue, app.GValue, app.BValue);
                end
                if value==0
                    app.RValue = zeros(size(app.imagOriginal(:,:,1)));
                    Rimg = cat(3, app.RValue, app.GValue, app.BValue);
                end
                imagesc(app.UIAxes, Rimg);
            else
                if value~=0
                    app.RValue = app.currentImage(:,:,value);
                    Rimg = cat(3, app.RValue, app.GValue, app.BValue);
                    app.currentImage = Rimg;
                end
            end
        end

        % Value changed function: BlueSlider
        function BlueSliderValueChanged(app, event)
            value = app.BlueSlider.Value;
            value = round(value);
            app.BlueSlider.Value=value;
            %if this function was called by an event then show the current
            %image. Else it just updates the current image with this filter.
            if isa(event, 'matlab.ui.eventdata.ValueChangedData')
                if value~=0
                    app.BValue = app.currentImage(:,:,value);
                    Bimg = cat(3, app.RValue, app.GValue, app.BValue);
                end
                if value==0
                    app.BValue = zeros(size(app.currentImage(:,:,1)));
                    Bimg = cat(3, app.RValue, app.GValue, app.BValue);
                end
                imagesc(app.UIAxes, Bimg);
            else
                if value~=0
                    app.BValue = app.currentImage(:,:,value);
                    Bimg = cat(3, app.RValue, app.GValue, app.BValue);
                    app.currentImage = Bimg;
                end
            end
        end

        % Value changed function: NoiseSlider
        function NoiseSliderValueChanged(app, event)
            value = app.NoiseSlider.Value;
            %if this function was called by an event then it will update
            %the image by calling the other functions then show the current
            %image. Else it just updates the current image with this filter.
            if isa(event, 'matlab.ui.eventdata.ValueChangedData')
                if value~=0
                    %calling the colors to be reapplied. Doing this ever so
                    %often throught the code makes it so the image looks better
                    app.currentImage = app.imagOriginal;
                    updateColor(app);
                    %creating a noise matrix then adding it to the current
                    %image
                    newImage = app.currentImage+value*(uint8(rand(size(app.currentImage)))-0.5);
                    app.currentImage = newImage;
                else
                    app.currentImage = app.imagOriginal;
                    updateColor(app);
                end
                updateFilters(app, "Noise");
                imagesc(app.UIAxes, app.currentImage);
            else
                if value~=0
                    newImage = app.currentImage+value*(uint8(rand(size(app.currentImage)))-0.5);
                    app.currentImage=newImage;
                end
            end
        end

        % Value changed function: GreenSlider
        function GreenSliderValueChanged(app, event)
            value = app.GreenSlider.Value;
            value = round(value);
            app.GreenSlider.Value=value;
            %if this function was called by an event then show the current
            %image. Else it just updates the current image with this filter.
            if isa(event, 'matlab.ui.eventdata.ValueChangedData')
                if value~=0
                    app.GValue = app.currentImage(:,:,value);
                    Gimg = cat(3, app.RValue, app.GValue, app.BValue);
                end
                if value==0
                    app.GValue = zeros(size(app.currentImage(:,:,1)));
                    Gimg = cat(3, app.RValue, app.GValue, app.BValue);
                end
                imagesc(app.UIAxes, Gimg);
            else
                if value~=0
                    app.GValue = app.currentImage(:,:,value);
                    Gimg = cat(3, app.RValue, app.GValue, app.BValue);
                    app.currentImage = Gimg;
                end
            end
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            %saving the current image with the name yourNewPhoto.
            imwrite(app.currentImage, 'yourNewPhoto.jpg');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {};
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.UIAxes.Visible = 'off';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.StartoverButton = uibutton(app.UIFigure, 'push');
            app.StartoverButton.ButtonPushedFcn = createCallbackFcn(app, @StartoverButtonPushed, true);
            app.StartoverButton.Text = 'Start over';
            app.NewImageButton = uibutton(app.UIFigure, 'push');
            app.NewImageButton.ButtonPushedFcn = createCallbackFcn(app, @NewImageButtonPushed, true);
            app.NewImageButton.Text = 'New Image';
            app.FiltersDropDownLabel = uilabel(app.UIFigure);
            app.FiltersDropDownLabel.Text = 'Filters';
            app.FiltersDropDown = uidropdown(app.UIFigure);
            app.FiltersDropDown.Items = {'None', 'Brighten', 'Gray', 'Details'};
            app.FiltersDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltersDropDownValueChanged, true);
            app.FiltersDropDown.Value = 'None';
            app.AdjustPanel = uipanel(app.UIFigure);
            app.AdjustPanel.Title = 'Adjust';
            app.RedSliderLabel = uilabel(app.AdjustPanel);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.AdjustPanel);
            app.RedSlider.Limits = [0 3];
            app.RedSlider.MajorTicks = [0 1 2 3];
            app.RedSlider.ValueChangedFcn = createCallbackFcn(app, @RedSliderValueChanged, true);
            app.RedSlider.MinorTicks = [];
            app.RedSlider.Value = 1;
            app.NoiseSliderLabel = uilabel(app.AdjustPanel);
            app.NoiseSliderLabel.Text = 'Noise';
            app.NoiseSlider = uislider(app.AdjustPanel);
            app.NoiseSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseSliderValueChanged, true);
            app.BlurSliderLabel = uilabel(app.AdjustPanel);
            app.BlurSliderLabel.Text = 'Blur';
            app.BlurSlider = uislider(app.AdjustPanel);
            app.BlurSlider.ValueChangedFcn = createCallbackFcn(app, @BlurSliderValueChanged, true);
            app.EdgesSliderLabel = uilabel(app.AdjustPanel);
            app.EdgesSliderLabel.Text = 'Edges';
            app.EdgesSlider = uislider(app.AdjustPanel);
            app.EdgesSlider.Limits = [0 10];
            app.EdgesSlider.MajorTicks = [0 2 4 6 8 10];
            app.EdgesSlider.MajorTickLabels = {'0', '2', '4', '6', '8', '10'};
            app.EdgesSlider.ValueChangedFcn = createCallbackFcn(app, @EdgesSliderValueChanged, true);
            app.GreenSliderLabel = uilabel(app.AdjustPanel);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.AdjustPanel);
            app.GreenSlider.Limits = [0 3];
            app.GreenSlider.MajorTicks = [0 1 2 3];
            app.GreenSlider.ValueChangedFcn = createCallbackFcn(app, @GreenSliderValueChanged, true);
            app.GreenSlider.MinorTicks = [];
            app.GreenSlider.Value = 2;
            app.BlueSliderLabel = uilabel(app.AdjustPanel);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.AdjustPanel);
            app.BlueSlider.Limits = [0 3];
            app.BlueSlider.MajorTicks = [0 1 2 3];
            app.BlueSlider.ValueChangedFcn = createCallbackFcn(app, @BlueSliderValueChanged, true);
            app.BlueSlider.MinorTicks = [];
            app.BlueSlider.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainFilter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end